classdef experiment_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SESSIONPanel                    matlab.ui.container.Panel
        ExperimenttypeDropDownLabel     matlab.ui.control.Label
        ExperimenttypeDropDown          matlab.ui.control.DropDown
        TrialnumberEditFieldLabel       matlab.ui.control.Label
        TrialnumberEditField            matlab.ui.control.NumericEditField
        TriallengthEditFieldLabel       matlab.ui.control.Label
        TriallengthEditField            matlab.ui.control.NumericEditField
        IntertrialperiodEditFieldLabel  matlab.ui.control.Label
        IntertrialperiodEditField       matlab.ui.control.NumericEditField
        Using2PCheckBox                 matlab.ui.control.CheckBox
        SessionIDEditFieldLabel         matlab.ui.control.Label
        SessionIDEditField              matlab.ui.control.NumericEditField
        SetupDropDownLabel              matlab.ui.control.Label
        SetupDropDown                   matlab.ui.control.DropDown
        PANELSPanel                     matlab.ui.container.Panel
        ModeDropDownLabel               matlab.ui.control.Label
        PanelMode                       matlab.ui.control.DropDown
        PatternnumberEditFieldLabel     matlab.ui.control.Label
        PatternnumberEditField          matlab.ui.control.NumericEditField
        FunctionnumberEditFieldLabel    matlab.ui.control.Label
        FunctionnumberEditField         matlab.ui.control.NumericEditField
        InitialposxdegEditFieldLabel    matlab.ui.control.Label
        InitialposxdegEditField         matlab.ui.control.NumericEditField
        PanelsSwitch                    matlab.ui.control.Switch
        InitialposydegEditFieldLabel    matlab.ui.control.Label
        InitialposydegEditField         matlab.ui.control.NumericEditField
        InitialposydegEditFieldLabel_2  matlab.ui.control.Label
        gainEditField_2Label            matlab.ui.control.Label
        gainEditField_2                 matlab.ui.control.NumericEditField
        InitialposydegEditFieldLabel_3  matlab.ui.control.Label
        WINDPanel                       matlab.ui.container.Panel
        ModeDropDown_2Label             matlab.ui.control.Label
        WindMode                        matlab.ui.control.DropDown
        WindSwitch                      matlab.ui.control.Switch
        AirflowLminLabel                matlab.ui.control.Label
        AirflowLminEditField            matlab.ui.control.NumericEditField
        gainEditFieldLabel              matlab.ui.control.Label
        gainEditField                   matlab.ui.control.NumericEditField
        ModulatedspeedCheckBox          matlab.ui.control.CheckBox
        StimspeedEditFieldLabel         matlab.ui.control.Label
        StimspeedEditField              matlab.ui.control.NumericEditField
        TurntypeDropDownLabel           matlab.ui.control.Label
        TurntypeDropDown                matlab.ui.control.DropDown
        EXPERIMENTSELECTIONGUILabel     matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %set out certain variables as properties so that they can be read by other callback functions
        experiment_dir;
        ball_directory;
        twop_directory;
    end
    
    methods (Access = private)
        
        function get_experiment_folder(app)
            %Create experiment folder
            genotype = inputdlg('Fly genotype (e.g. 60D05_6f)', 'Genotype');
            % Get the date and time
            date = datetime('today','Format', 'yyyyMMdd');
            time = datetime('now', 'Format', 'HH:mm');
            % Make the folders and set the dname
            app.ball_directory.Value = [app.experiment_dir.Value '\' char(datetime('today','Format', 'yyyyMMdd')) '_' char(genotype) '\ball'];
            app.twop_directory.Value = [app.experiment_dir.Value '\' char(datetime('today','Format', 'yyyyMMdd')) '_' char(genotype) '\2p'];
            if ~exist(app.ball_directory.Value)
                mkdir(app.ball_directory.Value);
                mkdir(app.twop_directory.Value);
                get_fly_metadata(app)
            else
                %If a folder for this fly already exists, ask the user what to do.
                answer = questdlg('Do you want to add to an existing fly?', 'Experiment Name Taken', 'Yes', 'No: new fly');
                switch answer
                    case 'Yes'
                    case 'No: new fly'
                        get_experiment_folder(app)
                        return
                end
            end
            
        end
        
        function get_fly_metadata(app)
            %prompt the user for information about the fly and save it in a mat file.
            prompt = {'Ecclosion date:','Starvation time (h):','Notes'};
            dlgtitle = 'Fly metadata';
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims);
            metadata = {answer(1),answer(2),answer(3)};
            save([app.ball_directory.Value,'\fly_metadata.mat'],'metadata')
        end
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.experiment_dir.Value = uigetdir('Z:\Wilson Lab\Mel\FlyOnTheBall\data\', 'Please chose an experiment directory.');
            app.DirectoryEditField.Value = app.experiment_dir.Value;
            get_experiment_folder(app)
            %The next couple lines are to bring the GUI back to the front (to focus on the app again)
            drawnow;
            figure(app.UIFigure);
                       
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %When the button is pushed, collect the values of the different fields, and run 'begin_trials'
            run_obj.experiment_ball_dir = app.ball_directory.Value;
            run_obj.num_trials = app.TrialnumberEditField.Value;
            run_obj.using_2p = app.Using2PCheckBox.Value;
            run_obj.experiment_type = app.ExperimenttypeDropDown.Value;
            run_obj.session_id = app.SessionIDEditField.Value;
            run_obj.experiment_dir = app.experiment_dir.Value;
            run_obj.trial_t = app.TriallengthEditField.Value;
            run_obj.inter_trial_t = app.IntertrialperiodEditField.Value;
            run_obj.set_up = app.SetupDropDown.Value;
            run_obj.start_pos_x = app.InitialposxdegEditField.Value;
            run_obj.start_pos_y = app.InitialposydegEditField.Value;
            
            run_obj.panel_status = app.PanelsSwitch.Value;
            run_obj.panel_mode = app.PanelMode.Value;
            run_obj.pattern_number = app.PatternnumberEditField.Value;
            run_obj.function_number = app.FunctionnumberEditField.Value;
            run_obj.gain_panels = app.gainEditField_2.Value;
                      
            run_obj.wind_status = app.WindSwitch.Value;   
            run_obj.wind_mode = app.WindMode.Value;
            run_obj.airflow = app.AirflowLminEditField;
            run_obj.gain_wind = app.gainEditField.Value;
            run_obj.modulated_speed = app.ModulatedspeedCheckBox;
            run_obj.stim_speed = app.StimspeedEditField.Value;
            run_obj.turn_type = app.TurntypeDropDown;
            
            %Run code to begin the trials
            next_session_id = begin_trials(run_obj);
            % Update session id
            app.SessionIDEditField.Value = next_session_id;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SESSIONPanel = uipanel(app.UIFigure);
            app.SESSIONPanel.Title = 'SESSION';
            app.ExperimenttypeDropDownLabel = uilabel(app.SESSIONPanel);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SESSIONPanel);
            app.ExperimenttypeDropDown.Items = {'Spontaneous_walking', 'Stimulus_jump', 'Gain_change'};
            app.ExperimenttypeDropDown.Value = 'Spontaneous_walking';
            app.TrialnumberEditFieldLabel = uilabel(app.SESSIONPanel);
            app.TrialnumberEditFieldLabel.Text = 'Trial number';
            app.TrialnumberEditField = uieditfield(app.SESSIONPanel, 'numeric');
            app.TrialnumberEditField.Value = 1;
            app.TriallengthEditFieldLabel = uilabel(app.SESSIONPanel);
            app.TriallengthEditFieldLabel.Text = 'Trial length';
            app.TriallengthEditField = uieditfield(app.SESSIONPanel, 'numeric');
            app.TriallengthEditField.Value = 30;
            app.IntertrialperiodEditFieldLabel = uilabel(app.SESSIONPanel);
            app.IntertrialperiodEditFieldLabel.Text = 'Inter-trial period';
            app.IntertrialperiodEditField = uieditfield(app.SESSIONPanel, 'numeric');
            app.Using2PCheckBox = uicheckbox(app.SESSIONPanel);
            app.Using2PCheckBox.Text = 'Using 2P';
            app.SessionIDEditFieldLabel = uilabel(app.SESSIONPanel);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.SessionIDEditField = uieditfield(app.SESSIONPanel, 'numeric');
            app.SetupDropDownLabel = uilabel(app.SESSIONPanel);
            app.SetupDropDownLabel.Text = 'Set-up';
            app.SetupDropDown = uidropdown(app.SESSIONPanel);
            app.SetupDropDown.Items = {'2P-room', 'WLI-TOBIN'};
            app.SetupDropDown.Value = '2P-room';
            app.PANELSPanel = uipanel(app.UIFigure);
            app.PANELSPanel.Title = 'PANELS';
            app.ModeDropDownLabel = uilabel(app.PANELSPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.PanelMode = uidropdown(app.PANELSPanel);
            app.PanelMode.Items = {'Closed_Loop', 'Open_Loop', 'Closed_Loop_X_Open_Loop_Y', 'Closed_Loop_X_Closed_Loop_Y'};
            app.PanelMode.Value = 'Closed_Loop';
            app.PatternnumberEditFieldLabel = uilabel(app.PANELSPanel);
            app.PatternnumberEditFieldLabel.Text = 'Pattern number';
            app.PatternnumberEditField = uieditfield(app.PANELSPanel, 'numeric');
            app.FunctionnumberEditFieldLabel = uilabel(app.PANELSPanel);
            app.FunctionnumberEditFieldLabel.Text = 'Function number';
            app.FunctionnumberEditField = uieditfield(app.PANELSPanel, 'numeric');
            app.InitialposxdegEditFieldLabel = uilabel(app.PANELSPanel);
            app.InitialposxdegEditFieldLabel.Text = 'Initial pos x (deg)';
            app.InitialposxdegEditField = uieditfield(app.PANELSPanel, 'numeric');
            app.InitialposxdegEditField.Limits = [0 359];
            app.PanelsSwitch = uiswitch(app.PANELSPanel, 'slider');
            app.InitialposydegEditFieldLabel = uilabel(app.PANELSPanel);
            app.InitialposydegEditFieldLabel.Text = 'Initial pos y (deg)';
            app.InitialposydegEditField = uieditfield(app.PANELSPanel, 'numeric');
            app.InitialposydegEditField.Limits = [0 359];
            app.InitialposydegEditFieldLabel_2 = uilabel(app.PANELSPanel);
            app.InitialposydegEditFieldLabel_2.Text = 'for static bar (#63)';
            app.gainEditField_2Label = uilabel(app.PANELSPanel);
            app.gainEditField_2Label.Text = 'gain';
            app.gainEditField_2 = uieditfield(app.PANELSPanel, 'numeric');
            app.gainEditField_2.Limits = [0 2];
            app.gainEditField_2.Value = 1;
            app.InitialposydegEditFieldLabel_3 = uilabel(app.PANELSPanel);
            app.InitialposydegEditFieldLabel_3.Text = 'for static bar, choose Closed_Loop and set the pos y.';
            app.WINDPanel = uipanel(app.UIFigure);
            app.WINDPanel.Title = 'WIND';
            app.ModeDropDown_2Label = uilabel(app.WINDPanel);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.WindMode = uidropdown(app.WINDPanel);
            app.WindMode.Items = {'Closed_Loop', 'Open_Loop'};
            app.WindMode.Value = 'Closed_Loop';
            app.WindSwitch = uiswitch(app.WINDPanel, 'slider');
            app.AirflowLminLabel = uilabel(app.WINDPanel);
            app.AirflowLminLabel.Text = 'Airflow (L/min)';
            app.AirflowLminEditField = uieditfield(app.WINDPanel, 'numeric');
            app.AirflowLminEditField.Limits = [0 2];
            app.gainEditFieldLabel = uilabel(app.WINDPanel);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.WINDPanel, 'numeric');
            app.gainEditField.Limits = [0 2];
            app.gainEditField.Value = 1;
            app.ModulatedspeedCheckBox = uicheckbox(app.WINDPanel);
            app.ModulatedspeedCheckBox.Text = 'Modulated speed';
            app.StimspeedEditFieldLabel = uilabel(app.WINDPanel);
            app.StimspeedEditFieldLabel.Text = 'Stim speed';
            app.StimspeedEditField = uieditfield(app.WINDPanel, 'numeric');
            app.TurntypeDropDownLabel = uilabel(app.WINDPanel);
            app.TurntypeDropDownLabel.Text = 'Turn type';
            app.TurntypeDropDown = uidropdown(app.WINDPanel);
            app.TurntypeDropDown.Items = {'clockwise', 'counterclockwise'};
            app.TurntypeDropDown.Value = 'clockwise';
            app.EXPERIMENTSELECTIONGUILabel = uilabel(app.UIFigure);
            app.EXPERIMENTSELECTIONGUILabel.Text = 'EXPERIMENT SELECTION GUI';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end